classdef pokerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PokerGameUIFigure    matlab.ui.Figure
        Label2               matlab.ui.control.Label
        BetErrorLabel        matlab.ui.control.Label
        StayButton           matlab.ui.control.Button
        RaiseButton          matlab.ui.control.Button
        EditField            matlab.ui.control.NumericEditField
        AIsbetLabel          matlab.ui.control.Label
        YourbetLabel         matlab.ui.control.Label
        YourbankLabel        matlab.ui.control.Label
        AIsbankLabel         matlab.ui.control.Label
        AIsBestHandLabel     matlab.ui.control.Label
        YourBestHandLabel    matlab.ui.control.Label
        NewHandButton        matlab.ui.control.Button
        WinnerLabel          matlab.ui.control.Label
        SeewhowonButton      matlab.ui.control.Button
        AddtoYourHandButton  matlab.ui.control.Button
        AddtoTableButton     matlab.ui.control.Button
        AddtoAIHandButton    matlab.ui.control.Button
        StartGameButton      matlab.ui.control.Button
        YourHandPanel        matlab.ui.container.Panel
        Label_9              matlab.ui.control.Label
        Label_8              matlab.ui.control.Label
        Image_16             matlab.ui.control.Image
        Image_9              matlab.ui.control.Image
        TablePanel           matlab.ui.container.Panel
        Label_7              matlab.ui.control.Label
        Label_6              matlab.ui.control.Label
        Label_5              matlab.ui.control.Label
        Label_4              matlab.ui.control.Label
        Label_3              matlab.ui.control.Label
        Image_15             matlab.ui.control.Image
        Image_14             matlab.ui.control.Image
        Image_13             matlab.ui.control.Image
        Image_12             matlab.ui.control.Image
        Image_10             matlab.ui.control.Image
        AIHandPanel          matlab.ui.container.Panel
        Label_2              matlab.ui.control.Label
        Label                matlab.ui.control.Label
        Image_11             matlab.ui.control.Image
        Image_2              matlab.ui.control.Image
    end


    properties (Access = public)
        hand = {'00','00'};
        cards = {'00' '00' '00' '00' '00' '00' '00'};
        table = {'00' '00' '00' '00' '00'};
        oppHand = {'00' '00'};

        card_list = ["AH" ,"2H" ,"3H" ,"4H" ,"5H" ,"6H" ,"7H" ,"8H" ,"9H" ,"TH" ,"JH" ,"QH" ,"KH" ,"AC" ,"2C" ,"3C" ,"4C" ,"5C" ,"6C" ,"7C" ,"8C" ,"9C" ,"TC" ,"JC" ,"QC" ,"KC" ,"AD" ,"2D" ,"3D" ,"4D" ,"5D" ,"6D" ,"7D" ,"8D" ,"9D" ,"TD" ,"JD" ,"QD" ,"KD" ,"AS" ,"2S" ,"3S" ,"4S" ,"5S" ,"6S" ,"7S" ,"8S" ,"9S" ,"TS" ,"JS" ,"QS" ,"KS"]

        nHand = 0;
        nOppHand = 0;
        nTable = 0;

        aiinitialMoney = 20000;
        oppinitialMoney = 20000;
        netTransfer=load('identifyCards.mat');
        inputSize = [0 0 0];
        cam = webcam(1);

        aibet = 0;
        oppbet = 0;

        handimageArr = {0 0}
        tableimageArr = {0 0 0 0 0}
        oppimageArr = {0 0}

        handlabelArr = {0 0};
        tablelabelArr = {0 0 0 0 0}
        opplabelArr = {0 0};

        cardback = imread('MATLABcard.jpg');
        allimgs = [];
        alllabels = [];
        ai_bestHand = '';
        opp_bestHand = '';

    end

    methods (Access = private)

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            app.hand = {'00','00'};
            app.cards = {'00' '00' '00' '00' '00' '00' '00'};
            app.table = {'00' '00' '00' '00' '00'};
            app.oppHand = {'00' '00'};
            app.nHand = 0;
            app.nOppHand = 0;
            app.nTable = 0;
            app.aiinitialMoney = 20000;
            app.oppinitialMoney = 20000;
            app.netTransfer=load('identifyCards.mat');
            app.WinnerLabel.Text = 'Winner: ';
            app.inputSize = app.netTransfer.netTransfer.Layers(1).InputSize;

            app.handimageArr = {app.Image_11 app.Image_2};
            app.tableimageArr = {app.Image_12 app.Image_13 app.Image_10 app.Image_14 app.Image_15 };
            app.oppimageArr = {app.Image_16 app.Image_9};

            app.handlabelArr = {app.Label app.Label_2};
            app.tablelabelArr = {app.Label_3 app.Label_4 app.Label_5 app.Label_6 app.Label_7};
            app.opplabelArr = {app.Label_8 app.Label_9};

            app.AIsbankLabel.Text = ['AI''s Bank: ' num2str(app.aiinitialMoney)];
            app.YourbankLabel.Text = ['Your Bank: ' num2str(app.oppinitialMoney)];

            % app.AIsBestHandLabel.Text = 'AI''s Best Hand: ';
            % app.YourBestHandLabel.Text = 'Your Best Hand: ';
            app.AIsbetLabel.Text = 'AI''s bet: ';
            app.YourbetLabel.Text = 'Your bet: ';
            preview(app.cam);
            app.allimgs = [app.handimageArr app.tableimageArr app.oppimageArr];
            app.alllabels = [app.handlabelArr app.tablelabelArr app.opplabelArr];
            for i = 1:length(app.allimgs)
                app.allimgs{i}.ImageSource = app.cardback;
            end
            for i = 1:length(app.alllabels)
                app.alllabels{i}.Visible = 'off';
            end

            app.StartGameButton.Visible = 0;
            app.AddtoYourHandButton.Visible = 1;
            app.AddtoAIHandButton.Visible = 0;
            app.AddtoTableButton.Visible = 0;
            app.SeewhowonButton.Visible = 0;
            app.NewHandButton.Visible = 0;
        end

        % Button pushed function: AddtoAIHandButton
        function AddtoAIHandButtonPushed(app, event)

            im = snapshot(app.cam);
            c = imresize(im,app.inputSize(1:2));
            out = app.netTransfer.netTransfer.classify(c);
            if app.nHand < 2
                app.nHand = app.nHand + 1;
                app.hand{app.nHand} = char(out);
                [app.aibet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                app.handimageArr{app.nHand}.ImageSource = im;
                app.handlabelArr{app.nHand}.Text = char(out);
                app.handlabelArr{app.nHand}.Visible = 'on';
                app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                % app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                % app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
            end
            if app.nHand == 2
                app.AddtoAIHandButton.Visible = 0;
                app.AddtoTableButton.Visible = 1;
            end
        end

        % Button pushed function: AddtoTableButton
        function AddtoTableButtonPushed(app, event)


            if app.nTable == 5
                app.SeewhowonButton.Visible = 1;
            elseif app.nTable == 3
                id = randi([1, 52],1);
                out = app.card_list(id);
                while (ismember(out, app.hand) || ismember(out, app.table) || ismember(out, app.oppHand))
                    id = randi([1, 52],1);
                    out = app.card_list(id);
                end
                im = imread("Cards/"+out+".jpg");
                app.nTable = app.nTable + 1;
                app.table{app.nTable} = char(out);
                [bet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                app.aibet = app.aibet + 0.3 * bet;
                [~, app.opp_bestHand] = build_hands_for_app([app.oppHand(1:app.nOppHand) app.table(1:app.nTable)],app.nOppHand + app.nTable);
                app.tableimageArr{app.nTable}.ImageSource = im;
                app.tablelabelArr{app.nTable}.Text = char(out);
                app.tablelabelArr{app.nTable}.Visible = 'on';
                % app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                % app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
                app.AddtoTableButton.Enable = 0;
                app.RaiseButton.Enable = 1;
                app.StayButton.Enable = 1;
                app.EditField.Enable = 1;
            elseif app.nTable == 4
                id = randi([1, 52],1);
                out = app.card_list(id);
                while (ismember(out, app.hand) || ismember(out, app.table) || ismember(out, app.oppHand))
                    id = randi([1, 52],1);
                    out = app.card_list(id);
                end
                im = imread("Cards/"+out+".jpg");
                app.nTable = app.nTable + 1;
                app.table{app.nTable} = char(out);
                [bet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                app.aibet = app.aibet + 0.3 * bet;
                [~, app.opp_bestHand] = build_hands_for_app([app.oppHand(1:app.nOppHand) app.table(1:app.nTable)],app.nOppHand + app.nTable);
                app.tableimageArr{app.nTable}.ImageSource = im;
                app.tablelabelArr{app.nTable}.Text = char(out);
                app.tablelabelArr{app.nTable}.Visible = 'on';
                % app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                % app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                % app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
                app.AddtoTableButton.Enable = 0;
                app.RaiseButton.Enable = 1;
                app.StayButton.Enable = 1;
                app.EditField.Enable = 1;
            elseif app.nTable < 3
                while app.nTable < 3
                    id = randi([1, 52],1);
                    out = app.card_list(id);
                    while (ismember(out, app.hand) || ismember(out, app.table) || ismember(out, app.oppHand))
                        id = randi([1, 52],1);
                        out = app.card_list(id);
                    end
                    im = imread("Cards/"+out+".jpg");
                    app.nTable = app.nTable + 1;
                    app.table{app.nTable} = char(out);
                    [bet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                    app.aibet = app.aibet + 0.3 * bet;
                    [~, app.opp_bestHand] = build_hands_for_app([app.oppHand(1:app.nOppHand) app.table(1:app.nTable)],app.nOppHand + app.nTable);
                    app.tableimageArr{app.nTable}.ImageSource = im;
                    app.tablelabelArr{app.nTable}.Text = char(out);
                    app.tablelabelArr{app.nTable}.Visible = 'on';
                    % app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                    app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                    % app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                    app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];

                end
                app.AddtoTableButton.Enable = 0;
                app.RaiseButton.Enable = 1;
                app.StayButton.Enable = 1;
                app.EditField.Enable = 1;
            end


        end

        % Button pushed function: AddtoYourHandButton
        function AddtoYourHandButtonPushed(app, event)
            im = snapshot(app.cam);
            c = imresize(im,app.inputSize(1:2));
            out = app.netTransfer.netTransfer.classify(c);
            if app.nOppHand < 2
                app.oppbet = 200;
                app.nOppHand = app.nOppHand + 1;
                app.oppHand{app.nOppHand} = char(out);
                [~, app.opp_bestHand] = build_hands_for_app([app.oppHand(1:app.nOppHand) app.table(1:app.nTable)],app.nOppHand + app.nTable);
                app.oppimageArr{app.nOppHand}.ImageSource = im;
                app.opplabelArr{app.nOppHand}.Text = char(out);
                app.opplabelArr{app.nOppHand}.Visible = 'on';
                % app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                % app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
            end
            if app.nOppHand == 2
                app.AddtoYourHandButton.Visible = 0;
                while app.nHand < 2
                    id = randi([1, 52],1);
                    out = app.card_list(id);

                    while (ismember(out, app.hand) || ismember(out, app.table) || ismember(out, app.oppHand))
                        id = randi([1, 52],1);
                        out = app.card_list(id);
                    end
                    % im = imread("Images/3C"+id+".png");
                    app.nHand = app.nHand + 1;
                    app.hand{app.nHand} = char(out);
                    [bet, app.ai_bestHand] = build_hands_for_app([app.hand(1:app.nHand) app.table(1:app.nTable)],app.nHand + app.nTable);
                    app.aibet = app.aibet + 0.3 * bet;
                    % app.handimageArr{app.nHand}.ImageSource = im;
                    app.handlabelArr{app.nHand}.Text = char(out);
                    % app.handlabelArr{app.nHand}.Visible = 'on';     % da commentare
                    % app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
                    app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.aibet)];
                    % app.YourBestHandLabel.Text = ['Your Best Hand: ' app.opp_bestHand];
                    app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
                end
                if app.nHand == 2
                    app.EditField.Visible = 1;
                    app.RaiseButton.Visible = 1;
                    app.StayButton.Visible = 1;

                end

                app.AddtoTableButton.Visible = 1;

            end
        end

        % Button pushed function: SeewhowonButton
        function SeewhowonButtonPushed(app, event)
            [~, app.opp_bestHand, oppRating, oppHC] = build_hands_for_app([app.oppHand app.table],app.nOppHand + app.nTable);
            [~, app.ai_bestHand, aiRating, aiHC] = build_hands_for_app([app.hand app.table],app.nHand + app.nTable);
            app.WinnerLabel.Visible = 1;
            for i = 1:2
                app.handlabelArr{i}.Visible = 'on';
                out = app.handlabelArr{i}.Text;
                im = imread("Cards/"+out+".jpg");
                app.handimageArr{i}.ImageSource = im;
            end
            % app.AIsBestHandLabel.Text = ['AI''s Best Hand: ' app.ai_bestHand];
            if oppRating < aiRating
                app.WinnerLabel.Text = [app.WinnerLabel.Text 'You Won!'];
                app.aiinitialMoney = app.aiinitialMoney - app.aibet;
                app.oppinitialMoney = app.oppinitialMoney + app.aibet;
            elseif oppRating == aiRating
                if oppHC > aiHC
                    app.WinnerLabel.Text = [app.WinnerLabel.Text 'You Won!'];
                    app.aiinitialMoney = app.aiinitialMoney - app.aibet;
                    app.oppinitialMoney = app.oppinitialMoney + app.aibet;
                elseif oppHC < aiHC
                    app.WinnerLabel.Text = [app.WinnerLabel.Text 'You lost'];
                    app.aiinitialMoney = app.aiinitialMoney + app.oppbet;
                    app.oppinitialMoney = app.oppinitialMoney - app.oppbet;
                else
                    app.WinnerLabel.Text = [app.WinnerLabel.Text 'It''s a tie'];
                end
            else
                app.WinnerLabel.Text = [app.WinnerLabel.Text 'You lost'];
                app.aiinitialMoney = app.aiinitialMoney + app.oppbet;
                app.oppinitialMoney = app.oppinitialMoney - app.oppbet;
            end
            app.SeewhowonButton.Visible = 0;
            app.NewHandButton.Visible = 1;
            app.EditField.Visible = 0;
            app.RaiseButton.Visible = 0;
            app.StayButton.Visible = 0;
            if app.aiinitialMoney <= 0
                app.Label2.Text = 'AI: I''m Bankrupt! Wanna play again?';
                app.StartGameButton.Visible = 1;

            elseif app.oppinitialMoney<= 0
                app.Label2.Text =  'AI: Hah I took all your money! Wanna play again?';
                app.StartGameButton.Visible = 1;
            end
            app.WinnerLabel.Visible = 'on';
            app.AIsbankLabel.Text = ['AI''s Bank: ' num2str(app.aiinitialMoney)];
            app.YourbankLabel.Text = ['Your Bank: ' num2str(app.oppinitialMoney)];
        end

        % Button pushed function: NewHandButton
        function NewHandButtonPushed(app, event)
            app.WinnerLabel.Text = 'Winner: ';
            app.nHand = 0;
            app.nTable = 0;
            app.nOppHand = 0;
            app.hand = {'00','00'};
            app.cards = {'00' '00' '00' '00' '00' '00' '00'};
            app.table = {'00' '00' '00' '00' '00'};
            app.oppHand = {'00' '00'};
            % app.AIsBestHandLabel.Text = 'AI''s Best Hand: ';
            % app.YourBestHandLabel.Text = 'Your Best Hand: ';
            app.AIsbetLabel.Text = 'AI''s bet: ';
            app.YourbetLabel.Text = 'Your bet: ';
            app.AddtoYourHandButton.Visible = 1;
            app.NewHandButton.Visible = 0;
            app.EditField.Enable = 1;
            app.RaiseButton.Enable = 1;
            app.StayButton.Enable = 1;
            app.WinnerLabel.Visible = 0;
            for i = 1:length(app.allimgs)
                app.allimgs{i}.ImageSource = app.cardback;
            end
            for i = 1:length(app.alllabels)
                app.alllabels{i}.Visible = 'off';
            end
        end

        % Button pushed function: RaiseButton
        function RaiseButtonPushed(app, event)
            if (app.oppbet + app.EditField.Value) > app.oppinitialMoney
                app.BetErrorLabel.Visible = 1;
            else
                app.oppbet = app.oppbet + app.EditField.Value;
                app.YourbetLabel.Text = ['Your bet: ' num2str(app.oppbet)];
                app.aibet = app.oppbet;
                app.AIsbetLabel.Text = ['AI''s bet: ' num2str(app.oppbet)];
                app.RaiseButton.Enable = 0;
                app.StayButton.Enable = 0;
                app.EditField.Enable = 0;
                app.BetErrorLabel.Visible = 0;
                app.AddtoTableButton.Enable = 1;
                if app.nHand == 0
                    app.AddtoAIHandButton.Visible = 1;
                elseif app.nTable == 3 || app.nTable == 4
                    app.AddtoTableButton.Enable = 1;
                elseif app.nTable == 5
                    app.AddtoTableButton.Visible = 0;
                    app.SeewhowonButton.Visible = 1;
                end
            end


        end

        % Button pushed function: StayButton
        function StayButtonPushed(app, event)
            app.RaiseButton.Enable = 0;
            app.StayButton.Enable = 0;
            app.EditField.Enable = 0;
            app.oppbet = app.aibet;
            app.YourbetLabel.Text = ['Your bet: ' app.AIsbetLabel.Text];
            app.AddtoTableButton.Enable = 1;
            if app.nHand == 0
                app.AddtoAIHandButton.Visible = 1;
            elseif app.nTable == 3 || app.nTable == 4
                app.AddtoTableButton.Enable = 1;
            elseif app.nTable == 5
                app.AddtoTableButton.Visible = 0;
                app.SeewhowonButton.Visible = 1;
            end
        end

        % Close request function: PokerGameUIFigure
        function PokerGameUIFigureCloseRequest(app, event)
            delete (app.cam)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PokerGameUIFigure = uifigure('Visible', 'off');
            app.PokerGameUIFigure.Name = 'Poker Game';
            app.PokerGameUIFigure.CloseRequestFcn = createCallbackFcn(app, @PokerGameUIFigureCloseRequest, true);
            app.AIHandPanel = uipanel(app.PokerGameUIFigure);
            app.AIHandPanel.Title = 'AI Hand';
            app.Image_2 = uiimage(app.AIHandPanel);
            app.Image_11 = uiimage(app.AIHandPanel);
            app.Label = uilabel(app.AIHandPanel);
            app.Label.Visible = 'off';
            app.Label_2 = uilabel(app.AIHandPanel);
            app.Label_2.Visible = 'off';
            app.TablePanel = uipanel(app.PokerGameUIFigure);
            app.TablePanel.Title = 'Table';
            app.Image_10 = uiimage(app.TablePanel);
            app.Image_12 = uiimage(app.TablePanel);
            app.Image_13 = uiimage(app.TablePanel);
            app.Image_14 = uiimage(app.TablePanel);
            app.Image_15 = uiimage(app.TablePanel);
            app.Label_3 = uilabel(app.TablePanel);
            app.Label_3.Visible = 'off';
            app.Label_4 = uilabel(app.TablePanel);
            app.Label_4.Visible = 'off';
            app.Label_5 = uilabel(app.TablePanel);
            app.Label_5.Visible = 'off';
            app.Label_6 = uilabel(app.TablePanel);
            app.Label_6.Visible = 'off';
            app.Label_7 = uilabel(app.TablePanel);
            app.Label_7.Visible = 'off';
            app.YourHandPanel = uipanel(app.PokerGameUIFigure);
            app.YourHandPanel.Title = 'Your Hand';
            app.Image_9 = uiimage(app.YourHandPanel);
            app.Image_16 = uiimage(app.YourHandPanel);
            app.Label_8 = uilabel(app.YourHandPanel);
            app.Label_8.Visible = 'off';
            app.Label_9 = uilabel(app.YourHandPanel);
            app.Label_9.Visible = 'off';
            app.StartGameButton = uibutton(app.PokerGameUIFigure, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.AddtoAIHandButton = uibutton(app.PokerGameUIFigure, 'push');
            app.AddtoAIHandButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoAIHandButtonPushed, true);
            app.AddtoAIHandButton.Visible = 'off';
            app.AddtoAIHandButton.Text = 'Add to AI Hand';
            app.AddtoTableButton = uibutton(app.PokerGameUIFigure, 'push');
            app.AddtoTableButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoTableButtonPushed, true);
            app.AddtoTableButton.Visible = 'off';
            app.AddtoTableButton.Text = 'Add to Table';
            app.AddtoYourHandButton = uibutton(app.PokerGameUIFigure, 'push');
            app.AddtoYourHandButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoYourHandButtonPushed, true);
            app.AddtoYourHandButton.Visible = 'off';
            app.AddtoYourHandButton.Text = 'Add to Your Hand';
            app.SeewhowonButton = uibutton(app.PokerGameUIFigure, 'push');
            app.SeewhowonButton.ButtonPushedFcn = createCallbackFcn(app, @SeewhowonButtonPushed, true);
            app.SeewhowonButton.Visible = 'off';
            app.SeewhowonButton.Text = 'See who won';
            app.WinnerLabel = uilabel(app.PokerGameUIFigure);
            app.WinnerLabel.Visible = 'off';
            app.WinnerLabel.Text = 'Winner: ';
            app.NewHandButton = uibutton(app.PokerGameUIFigure, 'push');
            app.NewHandButton.ButtonPushedFcn = createCallbackFcn(app, @NewHandButtonPushed, true);
            app.NewHandButton.Visible = 'off';
            app.NewHandButton.Text = 'New Hand';
            app.YourBestHandLabel = uilabel(app.PokerGameUIFigure);
            app.YourBestHandLabel.Text = 'Your Best Hand: ';
            app.AIsBestHandLabel = uilabel(app.PokerGameUIFigure);
            app.AIsBestHandLabel.Text = 'AI''s Best Hand: ';
            app.AIsbankLabel = uilabel(app.PokerGameUIFigure);
            app.AIsbankLabel.Text = 'AI''s bank: ';
            app.YourbankLabel = uilabel(app.PokerGameUIFigure);
            app.YourbankLabel.Text = 'Your bank: ';
            app.YourbetLabel = uilabel(app.PokerGameUIFigure);
            app.YourbetLabel.Text = 'Your bet: ';
            app.AIsbetLabel = uilabel(app.PokerGameUIFigure);
            app.AIsbetLabel.Text = 'AI''s bet: ';
            app.EditField = uieditfield(app.PokerGameUIFigure, 'numeric');
            app.EditField.Visible = 'off';
            app.RaiseButton = uibutton(app.PokerGameUIFigure, 'push');
            app.RaiseButton.ButtonPushedFcn = createCallbackFcn(app, @RaiseButtonPushed, true);
            app.RaiseButton.Visible = 'off';
            app.RaiseButton.Text = 'Raise';
            app.StayButton = uibutton(app.PokerGameUIFigure, 'push');
            app.StayButton.ButtonPushedFcn = createCallbackFcn(app, @StayButtonPushed, true);
            app.StayButton.Visible = 'off';
            app.StayButton.Text = 'Stay';
            app.BetErrorLabel = uilabel(app.PokerGameUIFigure);
            app.BetErrorLabel.Visible = 'off';
            app.BetErrorLabel.Text = 'Error: Can''t bet more money than you have!';
            app.Label2 = uilabel(app.PokerGameUIFigure);
            app.Label2.Visible = 'off';
            app.Label2.Text = 'Label2';
            % Show the figure after all components are created
            app.PokerGameUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PokerGameUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PokerGameUIFigure)
        end
    end
end